classdef doan2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        nghiemTab                      matlab.ui.container.Tab
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        hamEdit                        matlab.ui.control.EditField
        aEditFieldLabel                matlab.ui.control.Label
        aEdit                          matlab.ui.control.EditField
        bEditFieldLabel                matlab.ui.control.Label
        bEdit                          matlab.ui.control.EditField
        SaisEditFieldLabel             matlab.ui.control.Label
        ssEdit                         matlab.ui.control.EditField
        nghiemAxes                     matlab.ui.control.UIAxes
        ButtonGroup                    matlab.ui.container.ButtonGroup
        chiadoiButton                  matlab.ui.control.RadioButton
        lapButton                      matlab.ui.control.RadioButton
        tieptuyenButton                matlab.ui.control.RadioButton
        KtqunghimEditFieldLabel        matlab.ui.control.Label
        kqnEdit                        matlab.ui.control.EditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        sllEdit                        matlab.ui.control.EditField
        phanlyLabel                    matlab.ui.control.Label
        nghiemButton                   matlab.ui.control.Button
        NhphmlpEditFieldLabel          matlab.ui.control.Label
        hamlapEdit                     matlab.ui.control.EditField
        noiisuyTab                     matlab.ui.container.Tab
        NhpdliuxLabel                  matlab.ui.control.Label
        xEdit                          matlab.ui.control.EditField
        NhpdliuyLabel                  matlab.ui.control.Label
        yEdit                          matlab.ui.control.EditField
        ppnsButtonGroup                matlab.ui.container.ButtonGroup
        newtonButton                   matlab.ui.control.RadioButton
        lagrangeButton                 matlab.ui.control.RadioButton
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        kqdathucEdit                   matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        giatrinoisuyEdit               matlab.ui.control.EditField
        KtqunisuyEditFieldLabel        matlab.ui.control.Label
        kqnsEdit                       matlab.ui.control.EditField
        noisuyAxes                     matlab.ui.control.UIAxes
        noisuyButton                   matlab.ui.control.Button
        vensButton                     matlab.ui.control.Button
        hoiquyTab                      matlab.ui.container.Tab
        pphqButtonGroup                matlab.ui.container.ButtonGroup
        tuyentinhButton                matlab.ui.control.RadioButton
        hammuButton                    matlab.ui.control.RadioButton
        logaritButton                  matlab.ui.control.RadioButton
        hoiquyAxes                     matlab.ui.control.UIAxes
        NhpdliuxLabel_2                matlab.ui.control.Label
        xEdit_2                        matlab.ui.control.EditField
        NhpdliuyLabel_2                matlab.ui.control.Label
        yEdit_2                        matlab.ui.control.EditField
        KtquphngtrnhhiquyLabel         matlab.ui.control.Label
        kqphuongtrinhEdit              matlab.ui.control.EditField
        NhpgitrcndonLabel              matlab.ui.control.Label
        giatridudoanEdit               matlab.ui.control.EditField
        KtqudonLabel                   matlab.ui.control.Label
        kqdudoanEdit                   matlab.ui.control.EditField
        hoiquyButton                   matlab.ui.control.Button
        vehqButton                     matlab.ui.control.Button
        daohamTab                      matlab.ui.container.Tab
        NhpdliuxLabel_3                matlab.ui.control.Label
        xEdit_3                        matlab.ui.control.EditField
        NhpdliuyLabel_3                matlab.ui.control.Label
        yEdit_3                        matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        hamsoEdit                      matlab.ui.control.EditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        hEdit                          matlab.ui.control.EditField
        hoacLabel                      matlab.ui.control.Label
        daohamButtonGroup              matlab.ui.container.ButtonGroup
        xxtienButton                   matlab.ui.control.RadioButton
        xxluiButton                    matlab.ui.control.RadioButton
        xxtrungtamButton               matlab.ui.control.RadioButton
        taylorButton                   matlab.ui.control.RadioButton
        diffButton                     matlab.ui.control.RadioButton
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        giatritinhdhEdit               matlab.ui.control.EditField
        KtquEditFieldLabel             matlab.ui.control.Label
        kqdhEdit                       matlab.ui.control.EditField
        daohamButton                   matlab.ui.control.Button
        GitrsaisButtonGroup            matlab.ui.container.ButtonGroup
        OhButton                       matlab.ui.control.RadioButton
        Oh2Button                      matlab.ui.control.RadioButton
        tichphanTab                    matlab.ui.container.Tab
        hoacLabel_2                    matlab.ui.control.Label
        NhpdliuxLabel_4                matlab.ui.control.Label
        xEdit_4                        matlab.ui.control.EditField
        NhpdliuyLabel_4                matlab.ui.control.Label
        yEdit_4                        matlab.ui.control.EditField
        NhphmsEditFieldLabel_2         matlab.ui.control.Label
        hamsoEdit_2                    matlab.ui.control.EditField
        PhngphptchphnButtonGroup       matlab.ui.container.ButtonGroup
        hthangButton                   matlab.ui.control.RadioButton
        sson13Button                   matlab.ui.control.RadioButton
        sson38Button                   matlab.ui.control.RadioButton
        tuhamsoButton                  matlab.ui.control.RadioButton
        aEditFieldLabel_2              matlab.ui.control.Label
        aEdit_2                        matlab.ui.control.EditField
        bEditFieldLabel_2              matlab.ui.control.Label
        bEdit_2                        matlab.ui.control.EditField
        NhpcnabtnhtchphnLabel          matlab.ui.control.Label
        NhpNEditFieldLabel             matlab.ui.control.Label
        nEdit                          matlab.ui.control.EditField
        KtquEditField_2Label           matlab.ui.control.Label
        kqtichphanEdit                 matlab.ui.control.EditField
        tichphanButton                 matlab.ui.control.Button
        gthnhomTab                     matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: nghiemButton
        function nghiemButtonPushed(app, event)
            a = app.aEdit.Value;
            a = str2num(a);
            b = app.bEdit.Value;
            b = str2num(b);
            saiso = app.ssEdit.Value;
            saiso = str2num(saiso);
            f = app.hamEdit.Value;
            f = str2func(['@(x)' f]);   
            fp = app.hamlapEdit.Value;
            fp = str2func(['@(x)' fp]);
            if(app.chiadoiButton.Value)
                [nghiem, solanlap] = chiadoi(f,a,b,saiso);
            end
            if(app.lapButton.Value)          
                [nghiem, solanlap] = lap(fp,a,b,saiso);
            end
            if(app.tieptuyenButton.Value)
                [nghiem, solanlap] = tieptuyen(f,a,b,saiso);
            end
            app.kqnEdit.Value = num2str(nghiem);
            app.sllEdit.Value = num2str(solanlap);
            x = linspace(1,10);  
            plot(app.nghiemAxes,x,f(x));
        end

        % Button pushed function: noisuyButton
        function noisuyButtonPushed(app, event)
            X = app.xEdit.Value;
            X = str2num(X);
            Y = app.yEdit.Value;
            Y = str2num(Y);
            n = app.giatrinoisuyEdit.Value;
            n = str2num(n);
            if(app.newtonButton.Value)
                px = newton(X,Y);
            end
            if(app.lagrangeButton.Value)
                px = lagrange(X, Y);
            end
            px = str2func(['@(x)' char(px)]);
            app.kqdathucEdit.Value = func2str(px);
            a = px(n);
            app.kqnsEdit.Value = sprintf('%0.8f',a);
        end

        % Button pushed function: tichphanButton
        function tichphanButtonPushed(app, event)
            x = app.xEdit_4.Value;
            x = str2num(x);
            y = app.yEdit_4.Value;
            y = str2num(y);
            fx = app.hamsoEdit_2.Value;
            fx = str2func(['@(x)' fx]);
            a = app.aEdit_2.Value;
            a = str2num(a);
            b = app.bEdit_2.Value;
            b = str2num(b);
            N = app.nEdit.Value;
            N = str2num(N);
            if(app.hthangButton.Value)
                T = tichphanhinhthang(fx,a,b,N);
            end
            if(app.tuhamsoButton.Value)
                T = tphinhthangxy(x,y,a,b)
            end
            if(app.sson13Button.Value)
                T = tichphanSimpson13(x,y,a,b)
            end
            if(app.sson38Button.Value)
                T = tichphanSimpson38(x,y,a,b)
            end
            app.kqtichphanEdit.Value = sprintf('%0.8f', T);
        end

        % Button pushed function: hoiquyButton
        function hoiquyButtonPushed(app, event)
            x = app.xEdit_2.Value;
            x = str2num(x);
            y = app.yEdit_2.Value;
            y = str2num(y);
            n = app.giatridudoanEdit.Value;
            n = str2num(n);
            if(app.tuyentinhButton.Value)
                fx = hqtuyentinh(x,y);
            end
            if(app.hammuButton.Value)
                fx = hqhammu(x,y);           
            end
            if(app.logaritButton.Value)
                fx = hqmue(x,y);
            end
            fx = str2func(['@(x)' char(fx)]);
            app.kqphuongtrinhEdit.Value = func2str(fx);
            a = fx(n);
            app.kqdudoanEdit.Value = sprintf('%0.8f', a);
              
        end

        % Button pushed function: vehqButton
        function vehqButtonPushed(app, event)
            fx = app.kqphuongtrinhEdit.Value;
            fx = str2func(['@(x)' fx]);
            m = linspace(1,10);
            plot(app.hoiquyAxes, m,fx(m)); 
        end

        % Button pushed function: vensButton
        function vensButtonPushed(app, event)
            px = app.kqdathucEdit.Value;
            px = str2func(['@(x)' px]);
            m = linspace(1,10);
            plot(app.noisuyAxes,m,px(m));
        end

        % Button pushed function: daohamButton
        function daohamButtonPushed(app, event)
            x = app.xEdit_3.Value;
            x = str2num(x);
            y = app.yEdit_3.Value;
            y = str2num(y);
            n = app.giatritinhdhEdit.Value;
            n = str2num(n);
            h = app.hEdit.Value;
            h = str2num(h);
            fx = app.hamsoEdit.Value;
            fx = str2func(['@(x)' fx]);
            if(app.diffButton.Value)
                d1 = daoham(fx,n);
            end
            if(app.taylorButton.Value)
                d1 = taylor(x,y,h,n);
            end
            if(app.OhButton.Value)
                    if(app.xxtienButton.Value)
                        d1 = xxtienoh(x,y,h,n);
                    end
                    if(app.xxluiButton.Value)
                        d1 = xxluioh(x,y,h,n);
                    end
                    if(app.xxtrungtamButton.Value)
                        d1 = xxtrungtamoh(x,y,h,n)
                    end                       
            end
            if(app.Oh2Button.Value)
                    if(app.xxtienButton.Value)
                        d1 = xxtien(x,y,h,n);
                    end
                    if(app.xxluiButton.Value)
                        d1 = xxlui(x,y,h,n);
                    end
                    if(app.xxtrungtamButton.Value)
                        d1 = xxtrungtam(x,y,h,n)
                    end
            end 
            app.kqdhEdit.Value = sprintf('%0.3f',d1);   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.nghiemTab = uitab(app.TabGroup);
            app.nghiemTab.Title = 'Nghiệm';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.nghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.hamEdit = uieditfield(app.nghiemTab, 'text');
            app.aEditFieldLabel = uilabel(app.nghiemTab);
            app.aEditFieldLabel.Text = 'a';
            app.aEdit = uieditfield(app.nghiemTab, 'text');
            app.bEditFieldLabel = uilabel(app.nghiemTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEdit = uieditfield(app.nghiemTab, 'text');
            app.SaisEditFieldLabel = uilabel(app.nghiemTab);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.ssEdit = uieditfield(app.nghiemTab, 'text');
            app.nghiemAxes = uiaxes(app.nghiemTab);
            title(app.nghiemAxes, 'Đồ thị hàm số')
            xlabel(app.nghiemAxes, 'X')
            ylabel(app.nghiemAxes, 'Y')
            app.ButtonGroup = uibuttongroup(app.nghiemTab);
            app.ButtonGroup.Title = 'Phương pháp trính';
            app.chiadoiButton = uiradiobutton(app.ButtonGroup);
            app.chiadoiButton.Text = 'Chia đôi';
            app.chiadoiButton.Value = true;
            app.lapButton = uiradiobutton(app.ButtonGroup);
            app.lapButton.Text = 'Lặp';
            app.tieptuyenButton = uiradiobutton(app.ButtonGroup);
            app.tieptuyenButton.Text = 'Newton (tiếp tuyến)';
            app.KtqunghimEditFieldLabel = uilabel(app.nghiemTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.kqnEdit = uieditfield(app.nghiemTab, 'text');
            app.SlnlpEditFieldLabel = uilabel(app.nghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.sllEdit = uieditfield(app.nghiemTab, 'text');
            app.phanlyLabel = uilabel(app.nghiemTab);
            app.phanlyLabel.Text = 'Nhập khoảng phân ly nghiệm [a;b]';
            app.nghiemButton = uibutton(app.nghiemTab, 'push');
            app.nghiemButton.ButtonPushedFcn = createCallbackFcn(app, @nghiemButtonPushed, true);
            app.nghiemButton.Text = 'Run';
            app.NhphmlpEditFieldLabel = uilabel(app.nghiemTab);
            app.NhphmlpEditFieldLabel.Text = 'Nhập hàm lặp';
            app.hamlapEdit = uieditfield(app.nghiemTab, 'text');
            app.noiisuyTab = uitab(app.TabGroup);
            app.noiisuyTab.Title = 'Nội suy';
            app.NhpdliuxLabel = uilabel(app.noiisuyTab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x';
            app.xEdit = uieditfield(app.noiisuyTab, 'text');
            app.NhpdliuyLabel = uilabel(app.noiisuyTab);
            app.NhpdliuyLabel.Text = 'Nhập dữ liệu y';
            app.yEdit = uieditfield(app.noiisuyTab, 'text');
            app.ppnsButtonGroup = uibuttongroup(app.noiisuyTab);
            app.ppnsButtonGroup.Title = 'Phương pháp nội suy';
            app.newtonButton = uiradiobutton(app.ppnsButtonGroup);
            app.newtonButton.Text = 'Nội suy Newton';
            app.newtonButton.Value = true;
            app.lagrangeButton = uiradiobutton(app.ppnsButtonGroup);
            app.lagrangeButton.Text = 'Nội suy Lagrange';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.noiisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy ';
            app.kqdathucEdit = uieditfield(app.noiisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.noiisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.giatrinoisuyEdit = uieditfield(app.noiisuyTab, 'text');
            app.KtqunisuyEditFieldLabel = uilabel(app.noiisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.kqnsEdit = uieditfield(app.noiisuyTab, 'text');
            app.noisuyAxes = uiaxes(app.noiisuyTab);
            title(app.noisuyAxes, 'Đồ thị hàm số')
            xlabel(app.noisuyAxes, 'X')
            ylabel(app.noisuyAxes, 'Y')
            app.noisuyButton = uibutton(app.noiisuyTab, 'push');
            app.noisuyButton.ButtonPushedFcn = createCallbackFcn(app, @noisuyButtonPushed, true);
            app.noisuyButton.Text = 'Run';
            app.vensButton = uibutton(app.noiisuyTab, 'push');
            app.vensButton.ButtonPushedFcn = createCallbackFcn(app, @vensButtonPushed, true);
            app.vensButton.Text = 'Vẽ';
            app.hoiquyTab = uitab(app.TabGroup);
            app.hoiquyTab.Title = 'Hồi quy';
            app.pphqButtonGroup = uibuttongroup(app.hoiquyTab);
            app.pphqButtonGroup.Title = 'Phương pháp hồi quy';
            app.tuyentinhButton = uiradiobutton(app.pphqButtonGroup);
            app.tuyentinhButton.Text = 'Tuyến tính';
            app.tuyentinhButton.Value = true;
            app.hammuButton = uiradiobutton(app.pphqButtonGroup);
            app.hammuButton.Text = 'Hàm mũ';
            app.logaritButton = uiradiobutton(app.pphqButtonGroup);
            app.logaritButton.Text = 'Logarit';
            app.hoiquyAxes = uiaxes(app.hoiquyTab);
            title(app.hoiquyAxes, 'Đồ thị hàm số')
            xlabel(app.hoiquyAxes, 'X')
            ylabel(app.hoiquyAxes, 'Y')
            app.NhpdliuxLabel_2 = uilabel(app.hoiquyTab);
            app.NhpdliuxLabel_2.Text = 'Nhập dữ liệu x';
            app.xEdit_2 = uieditfield(app.hoiquyTab, 'text');
            app.NhpdliuyLabel_2 = uilabel(app.hoiquyTab);
            app.NhpdliuyLabel_2.Text = 'Nhập dữ liệu y';
            app.yEdit_2 = uieditfield(app.hoiquyTab, 'text');
            app.KtquphngtrnhhiquyLabel = uilabel(app.hoiquyTab);
            app.KtquphngtrnhhiquyLabel.Text = 'Kết quả phương trình hồi quy ';
            app.kqphuongtrinhEdit = uieditfield(app.hoiquyTab, 'text');
            app.NhpgitrcndonLabel = uilabel(app.hoiquyTab);
            app.NhpgitrcndonLabel.Text = 'Nhập giá trị cần dự đoán';
            app.giatridudoanEdit = uieditfield(app.hoiquyTab, 'text');
            app.KtqudonLabel = uilabel(app.hoiquyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.kqdudoanEdit = uieditfield(app.hoiquyTab, 'text');
            app.hoiquyButton = uibutton(app.hoiquyTab, 'push');
            app.hoiquyButton.ButtonPushedFcn = createCallbackFcn(app, @hoiquyButtonPushed, true);
            app.hoiquyButton.Text = 'Run';
            app.vehqButton = uibutton(app.hoiquyTab, 'push');
            app.vehqButton.ButtonPushedFcn = createCallbackFcn(app, @vehqButtonPushed, true);
            app.vehqButton.Text = 'Vẽ';
            app.daohamTab = uitab(app.TabGroup);
            app.daohamTab.Title = 'Đạo hàm';
            app.NhpdliuxLabel_3 = uilabel(app.daohamTab);
            app.NhpdliuxLabel_3.Text = 'Nhập dữ liệu x';
            app.xEdit_3 = uieditfield(app.daohamTab, 'text');
            app.NhpdliuyLabel_3 = uilabel(app.daohamTab);
            app.NhpdliuyLabel_3.Text = 'Nhập dữ liệu y';
            app.yEdit_3 = uieditfield(app.daohamTab, 'text');
            app.NhphmsEditFieldLabel = uilabel(app.daohamTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.hamsoEdit = uieditfield(app.daohamTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.daohamTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.hEdit = uieditfield(app.daohamTab, 'text');
            app.hoacLabel = uilabel(app.daohamTab);
            app.hoacLabel.Text = 'Hoặc';
            app.daohamButtonGroup = uibuttongroup(app.daohamTab);
            app.daohamButtonGroup.Title = 'Phương pháp đạo hàm';
            app.xxtienButton = uiradiobutton(app.daohamButtonGroup);
            app.xxtienButton.Text = 'Xấp xỉ tiến';
            app.xxtienButton.Value = true;
            app.xxluiButton = uiradiobutton(app.daohamButtonGroup);
            app.xxluiButton.Text = 'Xấp xỉ lùi';
            app.xxtrungtamButton = uiradiobutton(app.daohamButtonGroup);
            app.xxtrungtamButton.Text = 'Xấp xỉ trung tâm';
            app.taylorButton = uiradiobutton(app.daohamButtonGroup);
            app.taylorButton.Text = 'Tính từ dữ liệu x,y';
            app.diffButton = uiradiobutton(app.daohamButtonGroup);
            app.diffButton.Text = 'Tính từ hàm số';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.daohamTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.giatritinhdhEdit = uieditfield(app.daohamTab, 'text');
            app.KtquEditFieldLabel = uilabel(app.daohamTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.kqdhEdit = uieditfield(app.daohamTab, 'text');
            app.daohamButton = uibutton(app.daohamTab, 'push');
            app.daohamButton.ButtonPushedFcn = createCallbackFcn(app, @daohamButtonPushed, true);
            app.daohamButton.Text = 'Run';
            app.GitrsaisButtonGroup = uibuttongroup(app.daohamTab);
            app.GitrsaisButtonGroup.Title = 'Giá trị sai số';
            app.OhButton = uiradiobutton(app.GitrsaisButtonGroup);
            app.OhButton.Text = 'Oh';
            app.OhButton.Value = true;
            app.Oh2Button = uiradiobutton(app.GitrsaisButtonGroup);
            app.Oh2Button.Text = 'O(h^2)';
            app.tichphanTab = uitab(app.TabGroup);
            app.tichphanTab.Title = 'Tích phân';
            app.hoacLabel_2 = uilabel(app.tichphanTab);
            app.hoacLabel_2.Text = 'Hoặc';
            app.NhpdliuxLabel_4 = uilabel(app.tichphanTab);
            app.NhpdliuxLabel_4.Text = 'Nhập dữ liệu x';
            app.xEdit_4 = uieditfield(app.tichphanTab, 'text');
            app.NhpdliuyLabel_4 = uilabel(app.tichphanTab);
            app.NhpdliuyLabel_4.Text = 'Nhập dữ liệu y';
            app.yEdit_4 = uieditfield(app.tichphanTab, 'text');
            app.NhphmsEditFieldLabel_2 = uilabel(app.tichphanTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.hamsoEdit_2 = uieditfield(app.tichphanTab, 'text');
            app.PhngphptchphnButtonGroup = uibuttongroup(app.tichphanTab);
            app.PhngphptchphnButtonGroup.Title = 'Phương pháp tích phân';
            app.hthangButton = uiradiobutton(app.PhngphptchphnButtonGroup);
            app.hthangButton.Text = 'Hình thang từ hàm số';
            app.hthangButton.Value = true;
            app.sson13Button = uiradiobutton(app.PhngphptchphnButtonGroup);
            app.sson13Button.Text = 'Simpson 1/3';
            app.sson38Button = uiradiobutton(app.PhngphptchphnButtonGroup);
            app.sson38Button.Text = 'Simpson 3/8';
            app.tuhamsoButton = uiradiobutton(app.PhngphptchphnButtonGroup);
            app.tuhamsoButton.Text = 'Hình thang từ x, y';
            app.aEditFieldLabel_2 = uilabel(app.tichphanTab);
            app.aEditFieldLabel_2.Text = 'a';
            app.aEdit_2 = uieditfield(app.tichphanTab, 'text');
            app.bEditFieldLabel_2 = uilabel(app.tichphanTab);
            app.bEditFieldLabel_2.Text = 'b';
            app.bEdit_2 = uieditfield(app.tichphanTab, 'text');
            app.NhpcnabtnhtchphnLabel = uilabel(app.tichphanTab);
            app.NhpcnabtnhtchphnLabel.Text = 'Nhập cận [a;b] tính tích phân';
            app.NhpNEditFieldLabel = uilabel(app.tichphanTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.nEdit = uieditfield(app.tichphanTab, 'text');
            app.KtquEditField_2Label = uilabel(app.tichphanTab);
            app.KtquEditField_2Label.Text = 'Kết quả';
            app.kqtichphanEdit = uieditfield(app.tichphanTab, 'text');
            app.tichphanButton = uibutton(app.tichphanTab, 'push');
            app.tichphanButton.ButtonPushedFcn = createCallbackFcn(app, @tichphanButtonPushed, true);
            app.tichphanButton.Text = 'Run';
            app.gthnhomTab = uitab(app.TabGroup);
            app.gthnhomTab.Title = 'Giới thiệu nhóm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = doan2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end